classdef Volume_Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        MenuDropDownLabel      matlab.ui.control.Label
        MenuDropDown           matlab.ui.control.DropDown
        VolumeEditField        matlab.ui.control.NumericEditField
        SphereRad              matlab.ui.control.NumericEditField
        CalculateButton        matlab.ui.control.StateButton
        SphereLabel            matlab.ui.control.Label
        msg                    matlab.ui.control.Label
        VolumeLabel            matlab.ui.control.Label
        CubeSide               matlab.ui.control.NumericEditField
        CubeLabel              matlab.ui.control.Label
        CylinderRad            matlab.ui.control.NumericEditField
        CylinderRadLabel       matlab.ui.control.Label
        CylinderHeight         matlab.ui.control.NumericEditField
        CylinderHeightLabel    matlab.ui.control.Label
        ConeRad                matlab.ui.control.NumericEditField
        ConeRadLabel           matlab.ui.control.Label
        ConeHeight             matlab.ui.control.NumericEditField
        ConeHeightLabel        matlab.ui.control.Label
        PyLength               matlab.ui.control.NumericEditField
        PyLengthLabel          matlab.ui.control.Label
        PyWidth                matlab.ui.control.NumericEditField
        PyWidthLabel           matlab.ui.control.Label
        PyHeight               matlab.ui.control.NumericEditField
        PyHeightLabel          matlab.ui.control.Label
        RectLength             matlab.ui.control.NumericEditField
        RectLengthLabel        matlab.ui.control.Label
        RectWidth              matlab.ui.control.NumericEditField
        RectWidthLabel         matlab.ui.control.Label
        RectHeight             matlab.ui.control.NumericEditField
        RectHeightLabel        matlab.ui.control.Label
        Lamp                   matlab.ui.control.Lamp
        VolumeCalculatorLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function EditField_2ValueChanged(app, event)
      
            r = app.rEditField.Value;
            theta = app.EditField_4.Value;
            retangular = r*exp(1j*theta);
            app.RetangularFormEditField.Value = retangular;
        end

        % Callback function
        function EditFieldValueChanged(app, event)
          
            n = app.nthvalue.Value;
            %app.printing.Value = 
            for index = 1:n
                value = fib(index);
            end
            app.printing.Value = value;
            
        end

        % Callback function
        function CalculateButtonPushed(app, event)
            n = app.nthvalue.Value;
            %app.printing.Value = 
            %for index = 1:n
                app.printing.Value = fib(n);
            %end  
           % app.printing.Value = value;
        end

        % Value changed function: MenuDropDown
        function MenuDropDownValueChanged(app, event)
        value = app.MenuDropDown.Value;
        switch value
            case 'Sphere'
                app.SphereRad.Visible = true;
                app.SphereLabel.Text = 'Sphere Radius';
                app.CalculateButton.Visible = true;
                app.SphereLabel.Visible =  true;
                app.VolumeLabel.Visible = true;
                app.msg.Visible = false;
                app.CubeLabel.Visible = false;
                app.CubeSide.Visible = false;
                app.CylinderRadLabel.Visible = false;
                app.CylinderRad.Visible = false;
                app.CylinderHeight.Visible = false;
                app.CylinderHeightLabel.Visible = false;
                app.ConeHeight.Visible = false;
                app.ConeHeightLabel.Visible = false;
                app.ConeRad.Visible = false;
                app.ConeRadLabel.Visible = false;
                app.PyHeight.Visible = false;
                app.PyLength.Visible = false;
                app.PyWidth.Visible = false;
                app.PyHeightLabel.Visible = false;
                app.PyLengthLabel.Visible = false;
                app.PyWidthLabel.Visible = false;
                app.RectHeightLabel.Visible = false;
                app.RectLengthLabel.Visible = false;
                app.RectWidthLabel.Visible = false;
                app.RectHeight.Visible = false;
                app.RectLength.Visible = false;
                app.RectWidth.Visible = false;
                app.VolumeEditField.Visible = true;
                
            case 'Cylinder'
                app.SphereRad.Visible = false;
                app.CalculateButton.Visible = true;
                app.SphereLabel.Visible = false;
                app.VolumeLabel.Visible = true;
                app.msg.Visible = false;
                app.CubeLabel.Visible = false;
                app.CubeSide.Visible = false;
                app.CylinderRadLabel.Text = 'Cylinder Radius';
                app.CylinderHeightLabel.Text = 'Cylinder Height';
                app.CylinderRadLabel.Visible = true;
                app.CylinderRad.Visible = true;
                app.CylinderHeight.Visible = true;
                app.CylinderHeightLabel.Visible = true;
                app.ConeHeight.Visible = false;
                app.ConeHeightLabel.Visible = false;
                app.ConeRad.Visible = false;
                app.ConeRadLabel.Visible = false;
                app.PyHeight.Visible = false;
                app.PyLength.Visible = false;
                app.PyWidth.Visible = false;
                app.PyHeightLabel.Visible = false;
                app.PyLengthLabel.Visible = false;
                app.PyWidthLabel.Visible = false;
                app.RectHeightLabel.Visible = false;
                app.RectLengthLabel.Visible = false;
                app.RectWidthLabel.Visible = false;
                app.RectHeight.Visible = false;
                app.RectLength.Visible = false;
                app.RectWidth.Visible = false;
                app.VolumeEditField.Visible = true;

                
            case 'Cube'
                app.SphereRad.Visible = false;
                app.SphereLabel.Visible = false;
                app.CalculateButton.Visible = true;
                app.VolumeLabel.Visible = true;
                app.msg.Visible = false;
                app.CubeSide.Visible = true;
                app.CubeLabel.Visible = true;
                app.CubeLabel.Text = 'Side Length';
                app.CylinderRadLabel.Visible = false;
                app.CylinderRad.Visible = false;
                app.CylinderHeight.Visible = false;
                app.CylinderHeightLabel.Visible = false;
                app.ConeHeight.Visible = false;
                app.ConeHeightLabel.Visible = false;
                app.ConeRad.Visible = false;
                app.ConeRadLabel.Visible = false;
                app.PyHeight.Visible = false;
                app.PyLength.Visible = false;
                app.PyWidth.Visible = false;
                app.PyHeightLabel.Visible = false;
                app.PyLengthLabel.Visible = false;
                app.PyWidthLabel.Visible = false;
                app.RectHeightLabel.Visible = false;
                app.RectLengthLabel.Visible = false;
                app.RectWidthLabel.Visible = false;
                app.RectHeight.Visible = false;
                app.RectLength.Visible = false;
                app.RectWidth.Visible = false;
                app.VolumeEditField.Visible = true;
                
            case 'Cone'
                app.SphereRad.Visible = false;
                app.msg.Visible = false;
                app.CalculateButton.Visible = true;
                app.SphereLabel.Visible = false;
                app.VolumeLabel.Visible = true;
                app.CubeLabel.Visible = false;
                app.CubeSide.Visible = false;
                app.CylinderRadLabel.Visible = false;
                app.CylinderRad.Visible = false;
                app.CylinderHeight.Visible = false;
                app.CylinderHeightLabel.Visible = false;
                app.ConeHeight.Visible = true;
                app.ConeHeightLabel.Visible = true;
                app.ConeHeightLabel.Text = 'Cone Height';
                app.ConeRad.Visible = true;
                app.ConeRadLabel.Visible = true;
                app.ConeRadLabel.Text = 'Cone Radius';
                app.PyHeight.Visible = false;
                app.PyLength.Visible = false;
                app.PyWidth.Visible = false;
                app.PyHeightLabel.Visible = false;
                app.PyLengthLabel.Visible = false;
                app.PyWidthLabel.Visible = false;
                app.RectHeightLabel.Visible = false;
                app.RectLengthLabel.Visible = false;
                app.RectWidthLabel.Visible = false;
                app.RectHeight.Visible = false;
                app.RectLength.Visible = false;
                app.RectWidth.Visible = false;
                app.VolumeEditField.Visible = true;
         
            case 'Rectangular Pyramid'
                app.SphereRad.Visible = false;
                app.CalculateButton.Visible = true;
                app.SphereLabel.Visible = false;
                app.VolumeLabel.Visible = true;
                app.msg.Visible = false;
                app.CubeLabel.Visible = false;
                app.CubeSide.Visible = false;
                app.CylinderRadLabel.Visible = false;
                app.CylinderRad.Visible = false;
                app.CylinderHeight.Visible = false;
                app.CylinderHeightLabel.Visible = false;
                app.ConeHeight.Visible = false;
                app.ConeHeightLabel.Visible = false;
                app.ConeRad.Visible = false;
                app.ConeRadLabel.Visible = false;
                app.PyHeightLabel.Text = 'Pyramid Height';
                app.PyLengthLabel.Text = 'Pyramid Length';
                app.PyWidthLabel.Text = 'Pyramid Width';
                app.PyHeightLabel.Visible = true;
                app.PyLengthLabel.Visible = true;
                app.PyWidthLabel.Visible = true;
                app.PyHeight.Visible = true;
                app.PyLength.Visible = true;
                app.PyWidth.Visible = true;
                app.RectHeightLabel.Visible = false;
                app.RectLengthLabel.Visible = false;
                app.RectWidthLabel.Visible = false;
                app.RectHeight.Visible = false;
                app.RectLength.Visible = false;
                app.RectWidth.Visible = false;
                app.VolumeEditField.Visible = true;
                
            case 'Rectangular Prism'
                app.SphereRad.Visible = false;
                app.SphereLabel.Visible = false;
                app.msg.Visible = false;
                app.CalculateButton.Visible = true;
                app.VolumeLabel.Visible = true;
                app.CubeLabel.Visible = false;
                app.CubeSide.Visible = false;
                app.CylinderRadLabel.Visible = false;
                app.CylinderRad.Visible = false;
                app.CylinderHeight.Visible = false;
                app.CylinderHeightLabel.Visible = false;
                app.ConeHeight.Visible = false;
                app.ConeHeightLabel.Visible = false;
                app.ConeRad.Visible = false;
                app.ConeRadLabel.Visible = false;
                app.PyHeight.Visible = false;
                app.PyLength.Visible = false;
                app.PyWidth.Visible = false;
                app.PyHeightLabel.Visible = false;
                app.PyLengthLabel.Visible = false;
                app.PyWidthLabel.Visible = false;
                app.RectHeightLabel.Text = 'Rectangular Height';
                app.RectLengthLabel.Text = 'Rectangular Length';
                app.RectWidthLabel.Text = 'Rectangular Width';
                app.RectHeightLabel.Visible = true;
                app.RectLengthLabel.Visible = true;
                app.RectWidthLabel.Visible = true;
                app.RectHeight.Visible = true;
                app.RectLength.Visible = true;
                app.RectWidth.Visible = true;
                app.VolumeEditField.Visible = true;
                                
            case 'Select an Object' 
                app.SphereRad.Visible = false;
                app.SphereLabel.Visible = false;
                app.CalculateButton.Visible = false;
                app.msg.Visible = true;
                app.VolumeLabel.Visible = false;
                app.VolumeEditField.Visible = false;
                app.CubeLabel.Visible = false;
                app.CubeSide.Visible = false;
                app.CylinderRadLabel.Visible = false;
                app.CylinderRad.Visible = false;
                app.CylinderHeight.Visible = false;
                app.CylinderHeightLabel.Visible = false;
                app.ConeHeight.Visible = false;
                app.ConeHeightLabel.Visible = false;
                app.ConeRad.Visible = false;
                app.ConeRadLabel.Visible = false;
                app.PyHeight.Visible = false;
                app.PyLength.Visible = false;
                app.PyWidth.Visible = false;
                app.PyHeightLabel.Visible = false;
                app.PyLengthLabel.Visible = false;
                app.PyWidthLabel.Visible = false;
                app.RectHeightLabel.Visible = false;
                app.RectLengthLabel.Visible = false;
                app.RectWidthLabel.Visible = false;
                app.RectHeight.Visible = false;
                app.RectLength.Visible = false;
                app.RectWidth.Visible = false;
                
                app.Lamp.Visible = false;
                
            otherwise
                app.SphereRad.Visible = false;
                app.SphereLabel.Visible = false;
                app.CalculateButton.Visible = false;
                app.msg.Visible = false;
                app.VolumeLabel.Visible = false;
                app.VolumeEditField.Visible = false;
                app.CubeLabel.Visible = false;
                app.CubeSide.Visible = false;
                app.CylinderRadLabel.Visible = false;
                app.CylinderRad.Visible = false;
                app.CylinderHeight.Visible = false;
                app.CylinderHeightLabel.Visible = false;
                app.ConeHeight.Visible = false;
                app.ConeHeightLabel.Visible = false;
                app.ConeRad.Visible = false;
                app.ConeRadLabel.Visible = false;
                app.PyHeight.Visible = false;
                app.PyLength.Visible = false;
                app.PyWidth.Visible = false;
                app.PyHeightLabel.Visible = false;
                app.PyLengthLabel.Visible = false;
                app.PyWidthLabel.Visible = false;
                app.RectHeightLabel.Visible = false;
                app.RectLengthLabel.Visible = false;
                app.RectWidthLabel.Visible = false;
                app.RectHeight.Visible = false;
                app.RectLength.Visible = false;
                app.RectWidth.Visible = false;
                
                app.Lamp.Visible = false;
        end
        
        end

        % Value changed function: CalculateButton
        function CalculateButtonValueChanged(app, event)
            %value = app.CalculateButton.Value;
            sphrad = app.SphereRad.Value;
            side = app.CubeSide.Value;
            cylrad = app.CylinderRad.Value;
            cylhgt = app.CylinderHeight.Value;
            conRad = app.ConeRad.Value;
            conHei = app.ConeHeight.Value;
            pyHei = app.PyHeight.Value;
            pyLen = app.PyLength.Value;
            pywid = app.PyWidth.Value;
            rectHei = app.RectHeight.Value;
            rectLen = app.RectLength.Value;
            rectWid = app.RectWidth.Value;
           
            if sphrad > 0
                app.VolumeEditField.Value
                app.VolumeEditField.Value = (4/3) * pi * sphrad^3;
                
            elseif side > 0
                app.VolumeEditField.Value = side^3;
                
            elseif cylrad > 0
                app.VolumeEditField.Value = pi * cylrad^2 * cylhgt;
                
            elseif conRad > 0
                app.VolumeEditField.Value = pi * conRad^2 * (conHei / 3);
                
            elseif pyHei > 0
                app.VolumeEditField.Value = (1/3) * pyHei * pyLen * pywid;
                
            elseif rectHei > 0
                app.VolumeEditField.Value = rectLen * rectWid * rectHei;
                
            end
            app.Lamp.Visible = true;
            if app.VolumeEditField.Value <= 0
                app.Lamp.Color = 'red';
            else 
                app.Lamp.Color = 'g';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.MenuDropDownLabel = uilabel(app.Panel);
            app.MenuDropDownLabel.Text = 'Menu';
            app.MenuDropDown = uidropdown(app.Panel);
            app.MenuDropDown.Items = {'Select an Object', 'Cone', 'Cube', 'Cylinder', 'Rectangular Prism', 'Rectangular Pyramid', 'Sphere'};
            app.MenuDropDown.ValueChangedFcn = createCallbackFcn(app, @MenuDropDownValueChanged, true);
            app.MenuDropDown.Value = 'Select an Object';
            app.VolumeEditField = uieditfield(app.Panel, 'numeric');
            app.SphereRad = uieditfield(app.Panel, 'numeric');
            app.CalculateButton = uibutton(app.Panel, 'state');
            app.CalculateButton.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonValueChanged, true);
            app.CalculateButton.Text = 'Calculate';
            app.SphereLabel = uilabel(app.Panel);
            app.SphereLabel.Text = '';
            app.msg = uilabel(app.Panel);
            app.msg.Text = 'Plese select a type of obejct to continue.';
            app.VolumeLabel = uilabel(app.Panel);
            app.VolumeLabel.Text = 'Volume';
            app.CubeSide = uieditfield(app.Panel, 'numeric');
            app.CubeLabel = uilabel(app.Panel);
            app.CubeLabel.Text = '';
            app.CylinderRad = uieditfield(app.Panel, 'numeric');
            app.CylinderRadLabel = uilabel(app.Panel);
            app.CylinderRadLabel.Text = '';
            app.CylinderHeight = uieditfield(app.Panel, 'numeric');
            app.CylinderHeightLabel = uilabel(app.Panel);
            app.CylinderHeightLabel.Text = '';
            app.ConeRad = uieditfield(app.Panel, 'numeric');
            app.ConeRadLabel = uilabel(app.Panel);
            app.ConeRadLabel.Text = '';
            app.ConeHeight = uieditfield(app.Panel, 'numeric');
            app.ConeHeightLabel = uilabel(app.Panel);
            app.ConeHeightLabel.Text = '';
            app.PyLength = uieditfield(app.Panel, 'numeric');
            app.PyLengthLabel = uilabel(app.Panel);
            app.PyLengthLabel.Text = '';
            app.PyWidth = uieditfield(app.Panel, 'numeric');
            app.PyWidthLabel = uilabel(app.Panel);
            app.PyWidthLabel.Text = '';
            app.PyHeight = uieditfield(app.Panel, 'numeric');
            app.PyHeightLabel = uilabel(app.Panel);
            app.PyHeightLabel.Text = '';
            app.RectLength = uieditfield(app.Panel, 'numeric');
            app.RectLengthLabel = uilabel(app.Panel);
            app.RectLengthLabel.Text = '';
            app.RectWidth = uieditfield(app.Panel, 'numeric');
            app.RectWidthLabel = uilabel(app.Panel);
            app.RectWidthLabel.Text = '';
            app.RectHeight = uieditfield(app.Panel, 'numeric');
            app.RectHeightLabel = uilabel(app.Panel);
            app.RectHeightLabel.Text = '';
            app.Lamp = uilamp(app.Panel);
            app.VolumeCalculatorLabel = uilabel(app.UIFigure);
            app.VolumeCalculatorLabel.Text = 'Volume Calculator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Volume_Calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end